classdef EscherApp_standalone < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                     matlab.ui.Figure
        uipanel4                    matlab.ui.container.Panel
        ScreenSizeAngle             matlab.ui.control.Label
        ScreendistancewidthheightcmLabel  matlab.ui.control.Label
        ScreenDistance              matlab.ui.control.NumericEditField
        ScreenWidth                 matlab.ui.control.NumericEditField
        ScreenHeight                matlab.ui.control.NumericEditField
        WidthpixelsEditFieldLabel   matlab.ui.control.Label
        WidthPix                    matlab.ui.control.NumericEditField
        HeightpixelsEditFieldLabel  matlab.ui.control.Label
        HeightPix                   matlab.ui.control.NumericEditField
        uipanel2                    matlab.ui.container.Panel
        text10                      matlab.ui.control.Label
        text12                      matlab.ui.control.Label
        text13                      matlab.ui.control.Label
        GaussSize_txt               matlab.ui.control.EditField
        DriftingSpeed_txt           matlab.ui.control.EditField
        GridOrientation             matlab.ui.control.EditField
        SliderOrientation           matlab.ui.control.Slider
        uipanel9                    matlab.ui.container.ButtonGroup
        SineGrid                    matlab.ui.control.RadioButton
        SquareGrid                  matlab.ui.control.RadioButton
        Checkerboard_ck             matlab.ui.control.CheckBox
        GoGrid                      matlab.ui.control.Button
        DrawMask                    matlab.ui.control.CheckBox
        autosetgrids_btn            matlab.ui.control.Button
        FrequencycyclesDegLabel     matlab.ui.control.Label
        spatialFrequency            matlab.ui.control.NumericEditField
        MeanL01Label                matlab.ui.control.Label
        patternMeanL                matlab.ui.control.NumericEditField
        rangeLabel                  matlab.ui.control.Label
        plusPatternL                matlab.ui.control.NumericEditField
        rangeLabel_2                matlab.ui.control.Label
        minusPatternL               matlab.ui.control.NumericEditField
        ButtonGroup                 matlab.ui.container.ButtonGroup
        SinusoidalButton            matlab.ui.control.RadioButton
        SquareButton                matlab.ui.control.RadioButton
        CheckerboardButton          matlab.ui.control.RadioButton
        FullScreen                  matlab.ui.container.Panel
        FSgo                        matlab.ui.control.Button
        text61                      matlab.ui.control.Label
        fastset_txt                 matlab.ui.control.EditField
        Baselineluminance01EditFieldLabel  matlab.ui.control.Label
        FSbaselineL                 matlab.ui.control.NumericEditField
        FlashLabel                  matlab.ui.control.Label
        FSflashL                    matlab.ui.control.NumericEditField
        uipanel6                    matlab.ui.container.Panel
        OpenScreen                  matlab.ui.control.Button
        text38                      matlab.ui.control.Label
        StandbyL_txt                matlab.ui.control.EditField
        RefreshStBy                 matlab.ui.control.Button
        OneScreenMode_ck            matlab.ui.control.CheckBox
        CalibrateLUT                matlab.ui.control.CheckBox
        oculusCk                    matlab.ui.control.CheckBox
        calblack_btn                matlab.ui.control.Button
        calwhite_btn                matlab.ui.control.Button
        ard_ck                      matlab.ui.control.CheckBox
        text58                      matlab.ui.control.Label
        optDtrTimeTxt               matlab.ui.control.EditField
        text60                      matlab.ui.control.Label
        wwTxt                       matlab.ui.control.EditField
        hhTxt                       matlab.ui.control.EditField
        text60_2                    matlab.ui.control.Label
        AcceptIPEditFieldLabel      matlab.ui.control.Label
        AcceptedIP                  matlab.ui.control.EditField
        PortEditFieldLabel          matlab.ui.control.Label
        Port                        matlab.ui.control.NumericEditField
        StartIP                     matlab.ui.control.Button
        ServerModeCB                matlab.ui.control.CheckBox
        uipanel7                    matlab.ui.container.Panel
        text222                     matlab.ui.control.Label
        text43                      matlab.ui.control.Label
        CycleN                      matlab.ui.control.EditField
        OrienN                      matlab.ui.control.EditField
        Status                      matlab.ui.control.Label
        GoRandomGrid                matlab.ui.control.Button
        exit                        matlab.ui.control.Button
        text42                      matlab.ui.control.Label
        uipanel14                   matlab.ui.container.Panel
        InitialdelaysLabel          matlab.ui.control.Label
        initialDelay                matlab.ui.control.NumericEditField
        StimulussEditFieldLabel     matlab.ui.control.Label
        stimT                       matlab.ui.control.NumericEditField
        PeriodsLabel                matlab.ui.control.Label
        period                      matlab.ui.control.NumericEditField
        RepetitionsLabel            matlab.ui.control.Label
        stimRepetitions             matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
             
%            Definition of Sinusoidal gratings
             GridOrientationV % Reticle orientation
             SliderOrientationV % Valuer of orientation in lider control
             GaussSize % Space constant of the Gaussian mask
             SpatialFrequency % Guess what?
             DriftingSpeed % Speed of the drifting reticle

             MeanL % mean luminance of the reticle (in normalized units 0-1)
             RangeL % range of the reticle around the mean
             
%            Monitor initialisation and geometry

             ScreenDistanceV % distanc eof the screen from the observer
             ScreenWidthV % screen width (cm)
             WidthPixelsV % screen width in pixels
             HeightPixV % screen height in pixels
             ScreenHeightV % screen height (cm)
             StandbyL % screen luminance in stand by
             %OneScreenMode 
             
%            Random orientation
             OrienNum % number of random orientations per trial
             CycleNum % number of repetions
           
%            Additional private properties             
             masktex_on
             masktex_off
             
    end

    methods (Access = public)
        function generateStim(app, stim)
            % this public method allows to invoke Escher from an external app to control the generation
            % of a stimulus while the calling app continues its operations.
            % Input arguments:
            % app is the handle of the present istance of Escher
            % stim is a structure containing the strimulus description Theexact meaning of each field 
            % depends on stim.mode
            
            % stim.mode : FIELD, FLASH, SQUARE GRID, SIN GRID, CHECKER
            % Luminance decriptors (normalized in the 0-1 range)
            % stim.baselineL    luminance of uniform grey baseline preceding/following the stimulus sequence
            %                   FIELD the screen is set for an indefinite amount of time to this luminance
            %                   FLASH baseline value of screen luminance
            % stim.meanL        mean luminance of the stimulus
            %                   FLASH luminance of the flash
            % stim.rangeL       plus/minus range of the stimulus
            
            % Timing descriptors (in seconds)
            % stim.baselineT    duration of baseline (it can be zero)
            % stim.stimT        duration of the stim itself
            %                   CHECKER duration of one period (ckA plus ckB)
            %                   FLASH duration of the flash
            %                   GRID duration of the dirfting grating presentation
            % stim.period       period of the repetitive stimuli. This is how it is defined
            %                   FLASH: distance between successive flashes
            %                   CHECKER: twice stimT
            %                   GRID: each grid is followed by a post grid grey
            % stim.repeat       number of repetitions. the baseline is presented only ONCE at the beginning of 
            %                   the sequence. Use postT to separate the episodes
            
            % Additional descriptors
            % stim.SP           spatial frequency of the grid or of the checkerboard (cycles/deg)
            % stim.Speed        speed (deg/sec) of the drifting reticles
            % stim.Orien        orientation (degree) of the reticles
            % stim.GaussM       size of the Gauss mask in pixels. Set it to 0 to inactivate the mask.
           
            % perform actions depending on stim.Mode
            
            switch stim.mode
                case 'FIELD'
                    % set a uniform grey field

                case 'FLASH'
                    % set the flash stimulus sequence
                    app.Checkerboard_ck.Value = 0;     % inactivate checkerboard
                    app.FSbaselineL.Value = stim.baselineL;
                    app.FSflashL.Value = stim.meanL;
                    app.initialDelay.Value = stim.baselineT;
                    app.stimT.Value = stim.stimT;
                    app.period.Value = stim.period;
                    app.stimRepetitions.Value = stim.repeat;
                    FSgo_Callback(app)
                case 'CHECKER'
                    % set the checkerboard stimulus sequence
                    app.Checkerboard_ck.Value = 1;     % activate checkerboard
                    app.patternMeanL.Value = stim.patternMeanL; %the next 3 used to compute Blumi, Slumi
                    app.plusPatternL.Value = stim.plusPattern;
                    app.minusPatternL.Value = stim.minusPattern;
                    app.initialDelay.Value = stim.baselineT;
                    app.stimT.Value = stim.stimT;
                    app.period.Value = stim.period;
                    app.stimRepetitions.Value = stim.repeat;
                    checker(app)
                otherwise
                    % stim.mode has not been recognized.
            end
        end    
    end
    
    methods (Access = private)
        function checker (app)            
            % December, 2018. GMR
            % The app has been completey redesigned to implement a server-client architecture
                        
            % Nov 25, 2013.
            % Blumi and Slumi define the luminance of the black and light squares.
            % The spatial frequency is defined by the "spatial frequency" field in the
            % grid generation module.
            % p define the period of the alternation (ON and OFF)
            % Bt defines the baseline_txt spent in the baseline_txt equiluminant grey computed
            % as (Blumi+Slumi)/2 if a calibration LUT is active.
            
            % May 2017.
            % This code has been modified to allow the creation of the checkerboad stim
            % on the VR googles.
            
            global w screenRect ifi white black grey;
            global runNumberFile logDir runNumber;
            
            % load parameters in local variables
            Blumi = app.patternMeanL.Value + app.minusPatternL.Value;
            Slumi =  app.patternMeanL.Value + app.plusPatternL.Value;
            app.SpatialFrequency = app.spatialFrequency.Value;
            Bt = app.initialDelay.Value;
            St = app.stimT.Value;
            p = app.period.Value;
            n = app.stimRepetitions.Value;   % repetitions
            if app.CalibrateLUT.Value
                Blumi = LUTmonitor (round(Blumi)+1,2);
                Slumi = LUTmonitor (round(Slumi)+1,2);
            end
            
            ard_ck = app.ard_ck.Value;
            
            % load the WaitSecs function
            tim = WaitSecs (0);
            
            % CHECKERBOARD_CK STIMULATION
        
            % The checker board is generated by filling the entire screenRect with
            % squares. Two different "color arrays" are prepared for the
            % alternating pattern. The size of the squares is prepared according to
            % the spatial frequency defined in the grid GUI.
            % This is followed by a equiluminant grey of duration "Baseline". After
            % that the alternating stims start with period (one full on-off
            % transition) given by the term "Period".
    
            % Open the log file
            runNumber = dlmread(runNumberFile);
            %runString = int2str(runNumber);
            runString = sprintf('%04d',runNumber);
            logName = [logDir '\checkerboard.' runString '.log'];
            fid = fopen (logName, 'wt');
            % write the header containing the stim description. These are the 6
            % parameters defined in the Full Screen panel.
            fprintf (fid, '%.2f \t %.2f \t %.3f \t %.3f \t %d \t %d \n', Blumi, Slumi, Bt, St, p, n);
            runNumber = runNumber +1;
            dlmwrite(runNumberFile, runNumber)
            % Starts the OpenGL session if in single screen mode
            if app.OneScreenMode_ck.Value
                background = app.StandbyL;
                [w screenRect ifi white black grey] = OpenScreen (background);
            end
            % Next lines: insert the LUT correction
            Blumi = white*Blumi;             % Luminance expressed as fractions of 'white'
            Slumi = white*Slumi;
            gray = (Blumi+Slumi)/2;          % intermediate gray for equiluminant background
    
            % Compute angular size of single pixel
            Wpx = app.WidthPixelsV;
            ScWidth = app.ScreenWidthV;
            ScDist = app.ScreenDistanceV;
            OnePxAngle = atand((ScWidth/Wpx)/ScDist);    % atand returns the angle in degree
            f = OnePxAngle*app.SpatialFrequency;
            pixSize = ceil(1/f);
            oculusFlag = app.oculusCk.Value;
            try
                % compute the checkerboard_ck and the associate "colour" arrays
                side=pixSize/2;
                % screenRect contains the screen coordinates. x  y min (0 and 0,
                % strangely) and then x max and y max
                % If the oculusMode flag is true than the stimulu is prepared to be
                % displaied on the VR goggles. The screen width is divided in half
                % and two identical and aligned checkerboards are presented on each
                % screen
                if oculusFlag
                    halfWidth = screenRect(3)/2;
                    nSquareX = ceil((halfWidth/side));
                else
                    nSquareX = ceil(screenRect(3)/side);
                end
                nSquareY=ceil(screenRect(4)/side);
        
                if mod(nSquareX,2)==0
                    % the square row must be odd in order to have a correct
                    % alternation of on and off squares.
                    nSquareX=nSquareX+1;
                end
                % Total area (in pixels) occupied by the checkerboard_ck. If this is larger than
                % the screen it is cropped on the right and bottom sides.
                % if in "oculus mode" the crop is performed on the left side
            %        if oculusFlag,
                        % checkerW = side*nSquareX;
                        % winRectAdapted= [0 0 halfWidth side*nSquareY];
            %        else
                        winRectAdapted= [0 0 side*nSquareX side*nSquareY];
            %        end
                ntot=nSquareX*nSquareY;
                % Create the tiling squares stored in the array cellRects.
                % "ArrangeRects" is a function provided by the toolbox.
                % cellRects contains the rectangle coordinates. Two screen buffer are
                % created: one with the "white" stim and one with the "black".
                % Then, at the correct timing the screens are flipped.
        
                cellRects=ArrangeRects(ntot,[0 0 2 2],winRectAdapted);
            %         cellRects = [cellRects; [screenRect(3)/2, screenRect(4)/2, ...
            %             screenRect(3), screenRect(4)]];
                if oculusFlag
                    % now double the cellRects array... to do that 2 words about
                    % its structure: it contains the list of the xmin ymin xmax ymax
                    % coodinates of the checkers
                    checkerW = side*nSquareX;
                    for i=1:ntot
                        % now we build the second half of the screen by copying the
                        % left half plus the half width of the screen
                        cellRects2 (i,1) = cellRects (i,1) + halfWidth;
                        cellRects2 (i,3) = cellRects (i,3) + halfWidth;
                        cellRects2 (i,2) = cellRects (i,2);
                        cellRects2 (i,4) = cellRects (i,4);
                    end
                    if ard_ck %prepare the additional rectangle for optical dtr
                        sR = screenRect;
                        ww = str2double(app.wwTxt.Value); %width of the optical DTR
                        hh = str2double(app.hhTxt.Value); %height of the optical DTR
                        dtrRect = [sR(3)-ww, sR(4)-hh, sR(3), sR(4)];
                        cellRects2 = [cellRects2; dtrRect]; %right side
            %             % of course now there are twice as many checkers...
            %             %ntot = ntot * 2;
                        optDtrTime = str2double(app.optDtrTimeTxt.Value)/1000; % optical DTR duration in s
                    end
                else
                    if ard_ck
                        sR = screenRect;
                        ww = str2double(app.wwTxt.Value); %width of the optical DTR
                        hh = str2double(app.hhTxt.Value); %height of the optical DTR
                        dtrRect = [sR(3)-ww, sR(4)-hh, sR(3), sR(4)];
                        cellRects = [cellRects; dtrRect]; %right side
            %             % of course now there are twice as many checkers...
            %             %ntot = ntot * 2;
                        optDtrTime = str2double(app.optDtrTimeTxt.Value)/1000; % optical DTR duration in s
                    end
                end
    
                colOn = ones(3,ntot).*Slumi;
                colOff = zeros(3,ntot)+Blumi;
                colOn(1:3,1:2:ntot) = Blumi;
                colOff(1:3,1:2:ntot) = Slumi;
        
                if oculusFlag
                    colOn1 = colOn;
                    colOff1 = colOff;
                end
    
                if ard_ck
                    colOn2 = [colOn, [0; 0; 0]];
                    colOff2 = [colOff, [0; 0; 0]];
                    if oculusFlag
                        colOn1 = [colOn, [255; 255; 255]];
                        colOff1 = [colOff, [255; 255; 255]];
                    else
                        colOn=[colOn, [255; 255; 255]];
                        colOff = [colOff, [255; 255; 255]];
                    end
                    screenRectPlus = [screenRect; dtrRect];
                end
    
            % Fill screen with the baseline equiluminant grey
            % OK, let's start. First thing first: edge out and wait for end of baseline
            if ard_ck
                Screen('FillRect', w ,[[gray;gray;gray], [0;0;0]],screenRectPlus');     % paints the rectangle (entire screen)
            else
                Screen('FillRect', w ,gray ,screenRect);     % paints the rectangle (entire screen)
            end
            Screen('Flip', w);                           % with equiluminant gray.
            tim = WaitSecs (0);
            counter=0;
            Screen('FillRect',w,colOn,cellRects');
    %         if oculusFlag, Screen('FillRect',w,colOn,cellRects2');
            if oculusFlag, Screen('FillRect',w,colOn1,cellRects2'); % if "arduino" is checked,
                 % this will prepare a screen with the optical DTR on. Otherwise
                 % colOn1 is simply colOn
            end
            timA = WaitSecs ('UntilTime', tim+Bt) - tim;  % wait for the end of baseline
            tim = GetSecs;
            while (~KbCheck) && (counter < n)
                Screen('Flip',w);
                timNow = clock;       % write time of stimulus flip to log file. hh mm sec.x
                %--- switching to colOn2 for oculus
                if ard_ck
                    if oculusFlag
                        Screen('FillRect',w,colOn,cellRects')
                        Screen('FillRect',w,colOn2,cellRects2')
                    else
                        Screen('FillRect',w,colOn2,cellRects')
                    end
                    Screen('Flip',w, tim+2*p*counter+optDtrTime) %colOn1 stays on for "optDtrTime" s; arduino must wait more than that.
                end
                %---
    
                fprintf (fid,'%2d %2d %.3f \n', timNow(4), timNow(5), timNow(6));
    
                counter=counter+1;
    
                Screen('FillRect',w,colOff,cellRects');
                if oculusFlag, Screen('FillRect',w,colOff1,cellRects2'); % if "arduino" is checked,
                 % this will prepare a screen with the optical DTR on. Otherwise
                 % colOn1 is simply colOn
                end
                timA = WaitSecs ('UntilTime', tim+2*(counter-1)*p+p) - tim;
    
                Screen('Flip',w);
                %--- switching to colOff2 for oculus
                if ard_ck
                    if oculusFlag
                        Screen('FillRect',w,colOff,cellRects');
                        Screen('FillRect',w,colOff2,cellRects2');
                    else
                        Screen('FillRect',w,colOff2,cellRects');
                    end
                    Screen('Flip',w, tim+2*p*(counter-1)+p+optDtrTime); %colOn1 stays on for "optDtrTime" s; arduino must wait more than that.
                end
                %---
    
                Screen('FillRect',w,colOn,cellRects');
                if oculusFlag, Screen('FillRect',w,colOn1,cellRects2');% if "arduino" is checked,
                 % this will prepare a screen with the optical DTR on. Otherwise
                 % colOn1 is simply colOn
                end
                timA = WaitSecs ('UntilTime', tim+2*(counter-1)*p+2*p) - tim;
            end
            if ard_ck
                Screen('FillRect', w ,[[gray;gray;gray], [0;0;0]],screenRectPlus');     % paints the rectangle (entire screen)
            else
                Screen('FillRect', w ,gray ,screenRect);     % paints the rectangle (entire screen)
            end     % paints the rectangle (entire screen)
            Screen('Flip', w);                           % with equiluminant gray.
            tim = WaitSecs (0);
            timA = WaitSecs ('UntilTime', tim+Bt) - tim;  % wait for the end of baseline
    
            if app.OneScreenMode_ck.Value
                CloseScreen;
            end                         %Screen('CloseAll');
        catch
            Screen('CloseAll');
            psychrethrow(psychlasterror);
        end
            end
        end
    
    methods (Access = private)
        function AnimateGrid(app, movieDurationSecs, visiblesize, f, CyclesPerSecond, gratingtex, Angle, masktex, drawmask, revFlag)
            
            global w screenRect ifi
            
            % Definition of the drawn rectangle on the screen:
            % Compute it to  be the visible size of the grating, centered on the
            % screen:
            dstRect=[0 0 visiblesize visiblesize];
            dstRect=CenterRect(dstRect, screenRect);
            
            % Translate that into the amount of seconds to wait between screen
            % redraws/updates:
            
            % waitframes = 1 means: Redraw every monitor refresh. If your GPU is
            % not fast enough to do this, you can increment this to only redraw
            % every n'th refresh. All animation paramters will adapt to still
            % provide the proper grating. However, if you have a fine grating
            % drifting at a high speed, the refresh rate must exceed that
            % "effective" grating speed to avoid aliasing artifacts in time, i.e.,
            % to make sure to satisfy the constraints of the sampling theorem
            % (See Wikipedia: "Nyquist?Shannon sampling theorem" for a starter, if
            % you don't know what this means):
            waitframes = 1;
            
            % Translate frames into seconds for screen update interval:
            waitduration = waitframes * ifi;
            % Recompute p, this time without the ceil() operation from above.
            % Otherwise we will get wrong drift speed due to rounding errors!
            p=1/f;  % pixels/cycle
            
            % Translate requested speed of the grating (in cycles per second) into
            % a shift value in "pixels per frame", for given waitduration: This is
            % the amount of pixels to shift our srcRect "aperture" in horizontal
            % directionat each redraw:
            shiftperframe= CyclesPerSecond * p * waitduration;
            
            % Perform initial Flip to sync us to the VBL and for getting an initial
            % VBL-Timestamp as timing baseline for our redraw loop:
            vbl=Screen('Flip', w);
            
            % We run at most 'movieDurationSecs' seconds if user doesn't abort via keypress.
            vblendtime = vbl + movieDurationSecs;
            dtrendtime = vbl + 0.001*str2double(app.optDtrTimeTxt.Value);
            ii=0;
            % ...to here about 30 ms. GMR on his VAIO
            t=toc
            
            % Animationloop:
            while(vbl < vblendtime)
            
                % Shift the grating by "shiftperframe" pixels per frame:
                % the mod'ulo operation makes sure that our "aperture" will snap
                % back to the beginning of the grating, once the border is reached.
                % Fractional values of 'xoffset' are fine here. The GPU will
                % perform proper interpolation of color values in the grating
                % texture image to draw a grating that corresponds as closely as
                % technical possible to that fractional 'xoffset'. GPU's use
                % bilinear interpolation whose accuracy depends on the GPU at hand.
                % Consumer ATI hardware usually resolves 1/64 of a pixel, whereas
                % consumer NVidia hardware usually resolves 1/256 of a pixel. You
                % can run the script "DriftTexturePrecisionTest" to test your
                % hardware...
                xoffset = mod(ii*shiftperframe,p);
                ii=ii+1;
            
                % Define shifted srcRect that cuts out the properly shifted rectangular
                % area from the texture: We cut out the range 0 to visiblesize in
                % the vertical direction although the texture is only 1 pixel in
                % height! This works because the hardware will automatically
                % replicate pixels in one dimension if we exceed the real borders
                % of the stored texture. This allows us to save storage space here,
                % as our 2-D grating is essentially only defined in 1-D:
                srcRect=[xoffset 0 xoffset + visiblesize visiblesize];
            
                % Draw grating texture, rotated by "angle":
                Screen('DrawTexture', w, gratingtex, srcRect, dstRect, Angle);
            
                if app.ard_ck.Value
                    if vbl<dtrendtime
                        % Draw arduino mask over grating:
                        Screen('DrawTexture', w, app.masktex_on, screenRect, screenRect, Angle);
                    else
                        % Draw arduino mask over grating:
                        Screen('DrawTexture', w, app.masktex_off, screenRect, screenRect, Angle);
                    end
                end
            
                if drawmask==1
                    % Draw gaussian mask over grating:
                    Screen('DrawTexture', w, masktex, [0 0 visiblesize visiblesize], dstRect, Angle);
                end
            
                % Flip 'waitframes' monitor refresh intervals after last redraw.
                % Providing this 'when' timestamp allows for optimal timing
                % precision in stimulus onset, a stable animation framerate and at
                % the same time allows the built-in "skipped frames" detector to
                % work optimally and report skipped frames due to hardware
                % overload:
                vbl = Screen('Flip', w, vbl + (waitframes - 0.5) * ifi);
            
                % Abort function if any key is pressed:
                if KbCheck
                    break;
                end
            end
            if revFlag
                % now animate motion in the opposite direction
                vblendtime = vbl + movieDurationSecs;
                while(vbl < vblendtime)
                    xoffset = mod(-ii*shiftperframe,p);     % shift changes sign. God have mercy...
                    ii=ii+1;   % we keep the old value of ii
                    srcRect=[xoffset 0 xoffset + visiblesize visiblesize];
            
                    % Draw grating texture, rotated by "angle":
                    Screen('DrawTexture', w, gratingtex, srcRect, dstRect, Angle);
            
                    if drawmask==1
                        % Draw gaussian mask over grating:
                        Screen('DrawTexture', w, masktex, [0 0 visiblesize visiblesize], dstRect, Angle);
                    end
            
                    % Flip 'waitframes' monitor refresh intervals after last redraw.
                    % Providing this 'when' timestamp allows for optimal timing
                    % precision in stimulus onset, a stable animation framerate and at
                    % the same time allows the built-in "skipped frames" detector to
                    % work optimally and report skipped frames due to hardware
                    % overload:
                    vbl = Screen('Flip', w, vbl + (waitframes - 0.5) * ifi);
            
                    % Abort function if any key is pressed:
                    if KbCheck
                        break
                    end
                end
            end
        end
                
        function [gratingsize, visiblesize, gratingtex, masktex] = CreateTextures(app, GaussSize, maxL, f)
            % this function creates the grid texture (either square of sinusoidal grid)
            % of frequency f and the Gaus mask of size GaussSize
            
            global screenRect
            global w background
            global LUTmonitor
            
            % Define Half-Size of the grating image.
            gridType =  app.SineGrid.Value;
            gratingsize = GaussSize*4;
            texsize=gratingsize / 1;
            % Define size of gaussian mask
            
            % Contrast 'inc'rement range for given white and gray values:
            inc = maxL-background;
            
            % Calculate parameters of the grating:
            
            % First we compute pixels per cycle, rounded up to full pixels, as we
            % need this to create a grating of proper size below:
            p = ceil(1/f);
            
            % Also need frequency in radians:
            fr = f*2*pi;
            
            % This is the visible size of the grating. It is twice the half-width
            % of the texture plus one pixel to make sure it has an odd number of
            % pixels and is therefore symmetric around the center of the texture:
            visiblesize = 2*texsize+1;
            
            % Create one single static grating image:
            %
            % We only need a texture with a single row of pixels(i.e. 1 pixel in height) to
            % define the whole grating! If the 'srcRect' in the 'Drawtexture' call
            % below is "higher" than that (i.e. visibleSize >> 1), the GPU will
            % automatically replicate pixel rows. This 1 pixel height saves memory
            % and memory bandwith, ie. it is potentially faster on some GPUs.
            %
            % However it does need 2 * texsize + p columns, i.e. the visible size
            % of the grating extended by the length of 1 period (repetition) of the
            % sine-wave in pixels 'p':
            tic
            x = meshgrid(-texsize:texsize + p, 1);          % about 1 ms
            
            % Compute actual cosine grating:
            if gridType == 1
                grating = background + inc*cos(fr*x);                   % about 0.1 ms
                if app.CalibrateLUT.Value == 1
                    for ii = 1:1+2*texsize
                        gratings(ii) = LUTmonitor (round(gratings(ii))+1,2);
                    end
                end
            else
                GridUp = background+inc;
                GridDown = background-inc;
                if app.CalibrateLUT.Value
                    GridUp = LUTmonitor(round(GridUp)+1,2);
                    GridDown = LUTmonitor(round(GridDown)+1,2);
                end
                for ii = -texsize:texsize
                    if cos(fr*ii)>0
                        grating(ii+texsize+1) = GridUp;
                    else
                        grating(ii+texsize+1) = GridDown;
                    end
                end
            end
            % Store 1-D single row grating in texture:
            tic
            gratingtex = Screen('MakeTexture', w, grating);   % about 2.5 ms
            
            % Create a single gaussian transparency mask and store it to a texture:
            % The mask must have the same size as the visible size of the grating
            % to fully cover it. Here we must define it in 2 dimensions and can't
            % get easily away with one single row of pixels.
            %
            % We create a  two-layer texture: One unused luminance channel which we
            % just fill with the same color as the background color of the screen
            % 'gray'. The transparency (aka alpha) channel is filled with a
            % gaussian (exp()) aperture mask:
            
            % from here to ...
            mask=ones(2*texsize+1, 2*texsize+1, 2) * background;
            [x,y]=meshgrid(-1*texsize:1*texsize,-1*texsize:1*texsize);
            % here 300 ms CoolerMaster stimulation March 2012
            
            mask(:, :, 2)=255 * (1 - exp(-((x/GaussSize).^2)-((y/GaussSize).^2))); %255 in place of white
            masktex = Screen('MakeTexture', w, mask);         %270 ms March 2012
            
            if app.ard_ck.Value
                mask_on=zeros(screenRect(4), screenRect(3), 2);
                mask_on(:, :, 1) = 255;
                mask_on(end-str2double(app.hhTxt.Value):end, end-str2double(app.wwTxt.Value):end, 2) = 255;
            
                mask_off=zeros(screenRect(4), screenRect(3), 2);
                mask_off(end-str2double(app.hhTxt.Value):end, end-str2double(app.wwTxt.Value):end, 2)= 255; %255 in place of white
            
                app.masktex_on = Screen('MakeTexture', w, mask_on);         %270 ms March 2012
                app.masktex_off = Screen('MakeTexture', w, mask_off);
            end
        end
        
        function OneGridSequence(app, Angle, CyclesPerSecond, f, drawmask, Gsize, bl, GridT, PostG, MeanL, RangeL, n, handles)
            % function OneGridSequence(angle, cyclespersecond (period of grid drift), f (grid spatial period in px),
            % drawmask=1 to enable the Gauss mask, Gsize (mask size in px),
            % bl (baseline, sec before grid), GridT (duration in sec), PostG (delay after grid), n (number of repetitions)
            % Mean: luminance of screen during pre and post delays. Mean luminance during grid presentation.
            % RangeL: total luminance range of the grid around MeanL
            
            global w screenRect ifi white black grey background;
            
            global logDir runNumberFile runNumber
            
            % Open the log file
            runNumber = dlmread(runNumberFile);
            %runString = int2str(runNumber);
            runString = sprintf('%04d',runNumber);
            logName = [logDir '\grid.' runString '.log'];
            fid = fopen (logName, 'wt');
            % write the header containing the stim description. These are the 6
            % parameters defined in the Full Screen panel.
            fprintf (fid, '%d \t %.2f \t %.3f \t %.3f \t %.3f \t %d \n', Angle, CyclesPerSecond, f, bl, GridT, n);
            runNumber = runNumber +1;
            dlmwrite(runNumberFile, runNumber)
            
            if app.OneScreenMode == 1
                [w screenRect ifi white black grey] = OpenScreen (background);
                set (app.ScreenMap,'xlim',[screenRect(1) screenRect(3)]);
                set (app.ScreenMap,'ylim',[screenRect(2) screenRect(4)]);
                % Set the fields relative to screen size on the GUI and the relative app.variable.
                set (app.WidthPix,'String',int2str(screenRect(3)));
                set (app.HeightPix,'String',int2str(screenRect(4)));
                app.WidthPixelsV = screenRect(3);
                app.HeightPixV = screenRect(4);
                %guidata(hObject, handles);
            end
            background = white * MeanL;
            maxL = white * (MeanL + RangeL/2);
            if maxL > white then
                maxL = white;
            end
            totPeriod = bl + GridT +PostG;
            
            tim = WaitSecs (0);
            for i = 0:n-1
                Screen('FillRect', w ,background ,screenRect);     % paints the rectangle (entire screen)
                Screen('Flip', w);                                 % bring the buffered screen to forefront
            
                % write to the log file. These operation takes only 0.15 ms (0.6 ms
                % first call only).
                timNow = clock;                               % write time to log file. hh mm sec.x
            
                fprintf (fid,'%2d %2d %.3f \n', timNow(4), timNow(5), timNow(6));
            
                % wait for the end of baseline. Resync each period to the master timing
                % to compensate for the overhead of each statement
                timA = WaitSecs ('UntilTime', tim+bl+i*totPeriod) - tim;
                DriftGaussGridStandAlone (Angle,CyclesPerSecond,f,1,Gsize, w, screenRect, ifi,...
                    maxL, black, background, GridT, app.SineGrid.Value);

                % restore uniform screen of MeanL luminance
                Screen('FillRect', w ,background ,screenRect);     % paints the rectangle (entire screen)
                Screen('Flip', w);                                 % bring the buffered screen to forefront
                timA = WaitSecs ('UntilTime', tim+bl+GridT+PostG+i*totPeriod) - tim;   % wait end of PostG
            
            end
            fclose (fid);
            
            if app.OneScreenMode == 1
                CloseScreen
            end
        end
        
        function OneGrid_Callback(app, hObject, eventdata, handles)
            % hObject    handle to OneGrid (see GCBO)
            
            % compute parameters for function call
            
            Angle = app.GridOrientationV;
            speed = app.DriftingSpeed;
            Gsize = app.GaussSize;
            n = app.SingleGridRepetition;
            bl = app.Baseline;
            GridT = app.GridDuration;
            PostG = app.PostGrid;
            MeanL = app.MeanL;
            RangeL = app.RangeL;
            
            % Compute angular size of single pixel
            Wpx = app.WidthPixelsV;
            ScWidth = app.ScreenWidthV;
            ScDist = app.ScreenDistanceV;
            OnePxAngle = atand((ScWidth/Wpx)/ScDist);    % atand returns the angle in degree
            f = OnePxAngle*app.SpatialFrequency;
            
            % other parameters
            % OneGridSequence
            OneGridSequence (Angle,speed,f,1,Gsize,bl,GridT,PostG,MeanL,RangeL,n,handles)
        end
                
        function SetScreenParameters(app)
            % called after any changes of the screen geometry or position.
            WAngle = round(atand(app.ScreenWidthV/app.ScreenDistanceV));
            HAngle = round(atand(app.ScreenHeightV/app.ScreenDistanceV));
            Sz = ['Angular size of screen: ' int2str(WAngle) 'x' int2str(HAngle) ' degree'];
            app.ScreenSizeAngle.Text = Sz;
        end
                
        function prova2_Callback(app, hObject, eventdata, handles)
            
            Drift2Grids (110,10,0.05,1)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Escher2_OpeningFcn(app)
             
             Screen('Preference', 'SkipSyncTests', 1); %GAB FOR SMARTPHONE
             
%             % Choose default command line output for Escher2
%             app.output = hObject;
             
             global runNumberFile logDir runNumber ComHandle;            
             
%             % Initialisation code for EditText controls
%             % Here the content of each editable field of the GUI is loaded in a newly
%             % created variable in the 'app' structure. I am doing that since I
%             % find easier to use a app.variableName sintax to refer to a number
%             % rather than 'get'ting it from the control 'string' property. Of course
%             % code must be written inn the callback functions to keep the
%             % 'app.variable' sinched to the 'string' fields.
             
%            Sinusoidal gratings
             app.GridOrientationV = str2double(app.GridOrientation.Value);
             app.SliderOrientation.Value = app.GridOrientationV;
             app.GaussSize = str2double(app.GaussSize_txt.Value);
             app.DriftingSpeed = str2double(app.DriftingSpeed_txt.Value);
             
%             % Monitor initialisation and geometry

             app.ScreenDistanceV = str2double(app.ScreenDistance.Value);
             app.ScreenWidthV = str2double(app.ScreenWidth.Value);
             app.WidthPixelsV = str2double(app.WidthPix.Value);
             app.HeightPixV = str2double(app.HeightPix.Value);
             app.ScreenHeightV = str2double(app.ScreenHeight.Value);
             app.StandbyL = str2double(app.StandbyL_txt.Value);
             SetScreenParameters(app)
                          
%            Random orientation
             app.OrienNum = 8;
             app.CycleNum = str2double(app.CycleN.Value);
                          
%            Preparation of the log output
%             
%            Each time Esher is launched check the log directory C:\EscherLog for the
%            presence of a directory named with the current date. If it does not
%            exist it is created. If the directory exists already, mes1 returns
%            Directory already exists.
%            Content of the log directory.
%            Each time a stim event is created (either a single episode or an entire
%            sequence) a new entry is created in the log directory.
%            Sintax of the file name:
%            It is formed by two fields. The first field define the type of stimulus
%            flash      full field flash
%            grid       presentation of a grid at a gien orientaton
%            rndgrid    sequence of random orientations
%             
%            The second field is formed by a progressive 4 digit (completed with leading
%            0s) run number
             
%            finally the extension .log
             
%            Each run file includes a line that contains all the parameters relevant
%            to describe the specific stim protocol. Each presentation is described
%            in subsequent lines. The first field is a time tag, followed by
%            parameters desribing the specific stimulus, i.e. the grid orientation.
             
             logDir = ['c:\EscherLog\' date];
             [s,mes1] = mkdir (logDir);
             runNumberFile = [logDir '\run.number.log'];
             if strcmpi(mes1,'Directory already exists.')
                 % The log directory has already been created. Determine the next run number.
                 % This is contained in the ascii file run.number.log that is created at
                 % the time of the directory creation. Each time a new log item is added
                 % the run.number is updated. If Escher2 is re-initialisated during a
                 % single session, this ensure the unicity of the log file names.
                 runNumber = dlmread(runNumberFile);
             else
                 % Create the ASCII run.number.log file. run number = 1, of course
                 dlmwrite(runNumberFile, 1)
             end
        end

        % Value changed function: CalibrateLUT
        function CalibrateLUT_Callback(app, event)
            % hObject    handle to CalibrateLUT (see GCBO)
            % Hint: get(hObject,'Value') returns toggle state of CalibrateLUT
            
            global LUTmonitor
            
            if get(hObject,'Value') == 1
                LUTmonitor = dlmread ('LUT26aprile20.TAB');
            end
        end

        % Value changed function: Checkerboard_ck
        function Checkerboard_ck_Callback(app, event)
            try
                fastset_txt_Callback(app)
            catch ME
            end
        end

        % Value changed function: DriftingSpeed_txt
        function DriftingSpeed_txt_Callback(app, event)
            
            Speed = str2double(app.DriftingSpeed_txt.Value);
            if isnan(Speed)
                errordlg('You must enter a numeric value','Bad Input','modal')
            else
                app.DriftingSpeed = Speed;
            end
        end

        % Button pushed function: FSgo
        function FSgo_Callback(app, event)
            % December , 2018
            % The app has been completey redesigned to implement a server-client architecture
                        
            % Nov 25, 2013
            % the function has been modified to allow the display of a periodically
            % alternating checkboard.
            % Blumi and Slumi define the luminance of the black and light squares.
            % The spatial frequency is defined by the "spatial frequency" field in the
            % grid generation module.
            % p define the period of the alternation (ON and OFF)
            % Bt defines the baseline_txt spent in the baseline_txt equiluminant grey computed
            % as (Blumi+Slumi)/2 if a calibration LUT is active.
            
            % May 2017.
            % This code has been modified to allow the creation of the checkerboad stim
            % on the VR googles.
            
            global w screenRect ifi white black grey;
            global runNumberFile logDir runNumber;
            
            % load parameters in local variables
            Blumi = app.FSbaselineL.Value;
            Slumi = app.FSflashL.Value;
            Bt = app.initialDelay.Value;
            St = app.stimT.Value;
            p = app.period.Value;
            n = app.stimRepetitions.Value;   % repetitions
            if get(app.CalibrateLUT,'Value') == 1
                Blumi = LUTmonitor (round(Blumi)+1,2);
                Slumi = LUTmonitor (round(Slumi)+1,2);
            end
            
            ard_ck = app.ard_ck.Value;
            
            % load the WaitSecs function
            tim = WaitSecs (0);
            
            % FULL SCREEN STIMULATION
            % Open the log file
            runNumber = dlmread(runNumberFile);
            runString = sprintf('%04d',runNumber);
            logName = [logDir '\flash.' runString '.log'];
            fid = fopen (logName, 'wt');
            % write the header containing the stim description. These are the 6
            % parameters defined in the Full Screen panel.
            fprintf (fid, '%.2f \t %.2f \t %.3f \t %.3f \t %d \t %d \n', Blumi, Slumi, Bt, St, p, n);
            runNumber = runNumber +1;
            dlmwrite(runNumberFile, runNumber)
        
            % Starts the OpenGL session if in single screen mode
            if app.OneScreenMode_ck.Value
                background = app.StandbyL;
                [w screenRect ifi white black grey] = OpenScreen (background);
            end
            Blumi = white*Blumi;             % Luminance expressed as fractions of 'white'
            Slumi = white*Slumi;
            %screenRect = [300 300 500 500];
        
            if ard_ck %prepare the additional rectangle for optical dtr
                sR = screenRect;
                ww = str2double(app.wwTxt.Value); %width of the optical DTR
                hh = str2double(app.hhTxt.Value); %height of the optical DTR
                dtrRect = [sR(3)-ww, sR(4)-hh, sR(3), sR(4)];
                cellRects = [screenRect; dtrRect];
                optDtrTime = str2double(app.optDtrTimeTxt.Value)/1000; % optical DTR duration in s
            end
        
            timZero = WaitSecs (0);
            % December 2018. The initial delay is outside of the stimulus loop
            % Paint the screen for the initial delay
            % the following two lines takes a variable time in the range of 7-19 ms.
            if ard_ck
                Screen('FillRect', w ,[[Blumi;Blumi;Blumi], [0;0;0]], cellRects');
            else
                Screen('FillRect', w , Blumi ,screenRect);     % paints the rectangle (entire screen)
            end
    
            Screen('Flip', w);                            % bring the buffered screen to forefront
            % write to the log file. These operation takes only 0.15 ms (0.6 ms
            % first call only).
            timNow = clock;                               % write time to log file. hh mm sec.x
            fprintf (fid,'%2d %2d %.3f \n', timNow(4), timNow(5), timNow(6));
            timOffset = timZero + Bt;
            timA = WaitSecs ('UntilTime', timZero+Bt)-timZero;  % wait for the end of baseline
            
            for i = 1 : n
                % next lines flashes the screen with or without the Hermes rectangle. 6-12 ms on the Acer   
                %tic     % timing starts here
                if ard_ck %for flashes we suppose that stim duration is 20-100ms, which is compatible with Hermes detection and idle time
                    Screen('FillRect', w ,[[Slumi;Slumi;Slumi], [255;255;255]], cellRects');
                else
                    Screen('FillRect', w ,Slumi ,screenRect);
                end                    
                Screen('Flip', w);
                % toc     % 6-12 ms
                tim = WaitSecs(0);
                timB = WaitSecs ('UntilTime', tim + St)-timZero;    % wait for the end of stim
                % toc
                % the flash is complete
                if ard_ck
                    Screen('FillRect', w ,[[Blumi;Blumi;Blumi], [0;0;0]], cellRects');
                else
                    Screen('FillRect', w , Blumi ,screenRect);     % paint the rectangle (entire screen)
                end
                Screen('Flip', w);
                % toc
                % now wait for the end of the period
                timEnd = timOffset + i * p;
                timC = WaitSecs ('UntilTime', timEnd)-timZero;
                %toc
            end
            
            % close the log file
            fclose (fid);
            
            if app.OneScreenMode_ck.Value
                CloseScreen;
            end
        end

        % Value changed function: GaussSize_txt
        function GaussSize_txt_Callback(app, event)
            
            Gauss = str2double(app.GaussSize_txt.Value);
            if isnan(Gauss)
                errordlg('You must enter a numeric value','Bad Input','modal')
            else
                if Gauss < 400      % the grid size is computed as a multiple of the Gauss size. if
                                    % Gauss size is too big, the grid generation causes an error
                    app.GaussSize = Gauss;
                end
            end
        end

        % Button pushed function: GoGrid
        function GoGrid_Callback(app, event)
            
            global w screenRect ifi white black grey background;
            global logDir runNumberFile runNumber
            global LUTmonitor
            
            Angle = app.GridOrientationV;
            CyclesPerSecond = app.DriftingSpeed;
            GaussSize = app.GaussSize;
            n = app.SingleGridRepetition;
            bl = app.Baseline;
            GridT = app.GridDuration;
            PostG = app.PostGrid;
            MeanL = app.MeanL;
            RangeL = app.RangeL;
            revFlag = 0; %get(app.autoReverse,'Value');
            ard_ck = app.ard_ck.Value;
            
            % Compute angular size of single pixel
            Wpx = app.WidthPixelsV;
            ScWidth = app.ScreenWidthV;
            ScDist = app.ScreenDistanceV;
            OnePxAngle = atand((ScWidth/Wpx)/ScDist);    % atand returns the angle in degree
            f = OnePxAngle*app.SpatialFrequency;
            
            % start the sequence
            
            % Open the log file
            runNumber = dlmread(runNumberFile);
            %runString = int2str(runNumber);
            runString = sprintf('%04d',runNumber);
            logName = [logDir '\grid.' runString '.log'];
            fid = fopen (logName, 'wt');
            % write the header containing the stim description. These are the 6
            % parameters defined in the Full Screen panel.
            %fprintf (fid, '%d \t %.2f \t %.3f \t %.3f \t %.3f \t %d \n', Angle, CyclesPerSecond, f, bl, GridT, n);
            runNumber = runNumber +1;
            dlmwrite(runNumberFile, runNumber)
            
            if app.OneScreenMode_ck.Value
                [w screenRect ifi white black grey] = OpenScreen (MeanL);
                % Set the fields relative to screen size on the GUI and the relative app.variable.
                app.WidthPix.Value = int2str(screenRect(3));
                app.HeightPix.Value = int2str(screenRect(4));
                app.WidthPixelsV = screenRect(3);
                app.HeightPixV = screenRect(4);
            end
            
            if ard_ck % prepare the additional rectangle for optical synch signal
                sR = screenRect;
                ww = str2double(app.wwTxt.Value); %width of the optical DTR
                hh = str2double(app.hhTxt.Value); %height of the optical DTR
                dtrRect = [sR(3)-ww, sR(4)-hh, sR(3), sR(4)];
            end
            
            background = white * MeanL;
            if app.CalibrateLUT.Value
                CalBackground = LUTmonitor (round(background)+1,2);
            else
                CalBackground = background;
            end
            maxL = white * (MeanL + RangeL/2);
            if maxL > white then
                maxL = white;
            end
            totPeriod = bl + (revFlag+1) * GridT +PostG;
            
            tim = WaitSecs (0);
            for i = 0:n-1
                if ard_ck
                    Screen('FillRect', w ,[[CalBackground;CalBackground;CalBackground], [0;0;0]],[screenRect; dtrRect]');     % paints the rectangle (entire screen)
                else
                    Screen('FillRect', w ,CalBackground ,screenRect);     % paints the rectangle (entire screen)
                end
                Screen('Flip', w);                                 % bring the buffered screen to forefront
            
                % write to the log file. These operation takes only 0.15 ms (0.6 ms first call only VAIO).
                timNow = clock;                               % write time to log file. hh mm sec.x
                %fprintf (fid,'%2d %2d %.3f \n', timNow(4), timNow(5), timNow(6));
            
                try
                    % textures and mask are prepared at this time, waiting for the end of
                    % the baseline.
                    drawmask = app.DrawMask.Value;
                    [gratingsize, visiblesize, gratingtex, masktex] = CreateTextures (app, GaussSize,  maxL, f);
            
                    % wait for the end of baseline. Resync each period to the master timing
                    % to compensate for the overhead of each statement
                    timA = WaitSecs ('UntilTime', tim+bl+i*totPeriod) - tim;
            
                    % Baseline ended, start animation
                    AnimateGrid (app, GridT, visiblesize, f, CyclesPerSecond, gratingtex, Angle, masktex, drawmask,revFlag)
                catch
                    %this "catch" section executes in case of an error in the "try" section
                    %above.  Importantly, it closes the onscreen window if its open.
                    Screen('CloseAll');
                    Priority(0);
                    psychrethrow(psychlasterror);
                end %try..catch..
                Screen ('Close',gratingtex)
            
                %--------------------------------------------------------------------------
            
                % restore uniform screen of MeanL luminance
                if ard_ck
                    Screen('FillRect', w ,[[CalBackground;CalBackground;CalBackground], [0;0;0]],[screenRect; dtrRect]');     % paints the rectangle (entire screen)
                else
                    Screen('FillRect', w ,CalBackground ,screenRect);     % paints the rectangle (entire screen)
                end
                Screen('Flip', w);                                 % bring the buffered screen to forefront
                timA = WaitSecs ('UntilTime', tim+bl+GridT+PostG+i*totPeriod) - tim;   % wait end of PostG
            
            end
            fclose (fid);
            
            if app.OneScreenMode_ck.Value
                CloseScreen
            end
        end

        % Button pushed function: GoRandomGrid
        function GoRandomGrid_Callback(app, event)
            % hObject    handle to GoRandomGrid (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            global w screenRect ifi white black gray background;
            global logDir runNumberFile runNumber
            global ComHandle
            
            % compute parameters for function call
            
            CyclesPerSecond = app.DriftingSpeed;
            
            Gsize = app.GaussSize;
            bl = app.Baseline;
            GridT = app.GridDuration;
            PostG = app.PostGrid;
            MeanL = app.MeanL;
            RangeL = app.RangeL;
            nOr = app.OrienNum;
            nRepeat = app.CycleNum;       % this is the number of random repetition in the serie
            
            % Compute angular size of single pixel
            Wpx = app.WidthPixelsV;
            ScWidth = app.ScreenWidthV;
            ScDist = app.ScreenDistanceV;
            OnePxAngle = atand((ScWidth/Wpx)/ScDist);    % atand returns the angle in degree
            f = OnePxAngle*app.SpatialFrequency;
            
            % June 15, 2014.
            % Load the orientations in the angles array
            % Now the angle array is redefined and contains a different set of
            % orientations for each repeat. In all there are 8 different repeats.
            % Because of this the number of possible orientations is now fixed to 8
            %angleInc = 360 / nOr;
            %angles = 0:angleInc:359;
            
            % the first index cycles on the different trials, the second index cycles
            % on the orientations within a trial.
            % Here are defined the randomized sequences. This randomisation will be
            % employied in the data analysis kit to recover the orientation of each
            % data set.
            
            RndAngles=[315 45 135 270 0 180 90 225; 270 45 90 0 135 315 225 180; 0 225 135 45 90 315 180 270;
            90 0 270 135 225 180 315 45; 90 45 135 270 315 180 225 0; 270 135 315 45 0 225 90 180;
            90 0 225 315 270 45 135 180;135 270 0 180 315 225 45 90];
            
            % Open the log file
            % the log file is unvaried since Escher
            runNumber = dlmread(runNumberFile);
            runString = sprintf('%04d',runNumber);
            logName = [logDir '\rndGrid.' runString '.log'];
            fid = fopen (logName, 'wt');
            % write the header containing the stim description.
            % 1) spatial frequency (cyces x degree)
            % 2) drifting speed (cycles x second)
            % bl:       baseline duration (sec)
            % GridT:    grating presentation (sec)
            % nOr:      number of orientations in each serie
            % n:        repetions of the serie
            fprintf (fid, '%.2f \t %.2f \t %.3f \t %.3f \t %d \t %d \n', app.SpatialFrequency, CyclesPerSecond, bl, GridT, nOr, nRepeat);
            runNumber = runNumber +1;
            dlmwrite(runNumberFile, runNumber)
            
            if app.OneScreenMode_ck.Value
                background = MeanL;
                [w screenRect ifi white black gray] = OpenScreen (background);
                % Set the fields relative to screen size on the GUI and the relative app.variable.
                app.WidthPix.Value = int2str(screenRect(3));
                app.HeightPix.Value = int2str(screenRect(4));
                app.WidthPixelsV = screenRect(3);
                app.HeightPixV = screenRect(4);
            end
            
            background = white * MeanL;
            maxL = white * (MeanL + RangeL/2);
            if maxL > white
                maxL = white;
            end
            totPeriod = bl + GridT + PostG;
            
            % cycle on sequences
            tim = WaitSecs (0);     % save the high precision timer. This is the time origin.
            for serie = 1:nRepeat
                % in the array angles is copied the rnd sequence
                angles=RndAngles(serie,:);
                for i = 1:nOr
                    %app.Status.Text = ['sweep ',int2str(i),' of ',int2str(serie)];
                    Screen('FillRect', w ,background ,screenRect);     % paints the rectangle (entire screen)
                    Screen('Flip', w);                                 % bring the buffered screen to forefront
                    a = angles(i);
                    % write to the log file. These operation takes only 0.15 ms (0.6 ms
                    % first call only).
                    timNow = clock;                               % write time to log file. hh mm sec.x
                    fprintf (fid,'%3d %2d %2d %.3f \n', a, timNow(4), timNow(5), timNow(6));
            
                    % textures and mask are prepared at this time, waiting for the end of
                    % the baseline.
                    drawmask = get(app.DrawMask,'value');
                    [gratingsize, visiblesize, gratingtex, masktex] = CreateTextures (app, Gsize,  maxL, f);
            
                    % wait for the end of baseline. Resync each period to the master timing
                    % to compensate for the overhead of each statement
                    timA = WaitSecs ('UntilTime', tim+bl+nOr*(serie-1)*totPeriod+(i-1)*totPeriod) - tim;
            
                    % now display the grid and slide it!!!
                    % Baseline ended, start animation
            revFlag=0
                    AnimateGrid (app,GridT, visiblesize, f, CyclesPerSecond, gratingtex, a, masktex, drawmask,revFlag)
            
                    % the code returns here ony at the end of the grid display
                    % restore uniform screen of MeanL luminance
                    Screen('FillRect', w ,background ,screenRect);     % paints the rectangle (entire screen)
                    Screen('Flip', w);                                 % bring the buffered screen to forefront
                    timA = WaitSecs ('UntilTime', tim+bl+GridT+PostG+nOr*(serie-1)*totPeriod+(i-1)*totPeriod) - tim;   % wait end of PostG
                end
            end
            
            fclose (fid);
            
            if app.OneScreenMode_ck.Value
                CloseScreen
            end
        end

        % Callback function
        function GridDuration_txt_Callback(app, event)
            
            GridT = str2double(app.GridDuration_txt.Value);
            if isnan(GridT)
                errordlg('You must enter a numeric value','Bad Input','modal')
                %uicontrol(hObject)
            else
                app.GridDuration = GridT;
            end
        end

        % Value changed function: GridOrientation
        function GridOrientation_Callback(app, event)
            % hObject    handle to GridOrientation (see GCBO)
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of GridOrientation as text
            %        str2double(get(hObject,'String')) returns contents of GridOrientation as a double
            
            GridAngle = str2double(app.GridOrientation.Value);
            if isnan(GridAngle)
                errordlg('You must enter a numeric value','Bad Input','modal')
            else
                app.GridOrientationV = GridAngle;
                app.SliderOrientation.Value = app.GridOrientationV;
            end
        end

        % Callback function
        function HeightPix_Callback(app, event)
            % hObject    handle to HeightPix (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            ScHpx = str2double(app.HeightPix.Value);
            if isnan(ScHpx)
                errordlg('You must enter a numeric value','Bad Input','modal');
            else
                app.HeightPixV = abs(ScHpx);
            end
        end

        % Callback function
        function MeanGridLuminance_Callback(app, event)
            
            mL = str2double(app.MeanGridLuminance.Value);
            if isnan(mL)
                errordlg('You must enter a numeric value','Bad Input','modal')
            else
                if mL < 1
                    app.MeanL = mL;
                end
            end
        end

        % Value changed function: OneScreenMode_ck
        function OneScreenMode_ck_Callback(app, event)
            value = app.OneScreenMode_ck.Value;
            
        end

        % Button pushed function: OpenScreen
        function OpenScreen_Callback(app, event)
            
            global w screenRect ifi white black grey background;
            global LUTmonitor;
            
            background = app.StandbyL;
            
            if get(app.CalibrateLUT,'Value') == 1
                temp = round(background * 255)+1
                background = LUTmonitor(temp,2)/255
            end
            
            % screenRect is a 4 element array xmin, ymin, xmax, ymax
            % strangely, xmin and ymin are set to zero. Bho?
            [w screenRect ifi white black grey] = OpenScreen (background);
            
            % Remember to set the fields relative to the screen size on the GUI and the
            % relative app.variable.
            app.WidthPix.Value = screenRect(3);
            app.HeightPix.Value = screenRect(4);
            app.WidthPixelsV = screenRect(3);
            app.HeightPixV = screenRect(4);

        end

        % Value changed function: OrienN
        function OrienN_Callback(app, event)
            % hObject    handle to OrienN (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            nOr = str2double(get(hObject,'String'));
            
            if isnan(nOr) || nOr < 1
                errordlg('You must enter a positive numeric value','Bad Input','modal');
                uicontrol(hObject);
            else
                app.OrienN = nOr;
                guidata(hObject,handles);
            end
        end

        % Callback function
        function PostGrid_txt_Callback(app, event)
            
            Post = str2double(app.PostGrid_txt.Value);
            if isnan(Post)
                errordlg('You must enter a numeric value','Bad Input','modal')
                %uicontrol(hObject)
            else
                app.PostGrid = Post;
            end
        end

        % Callback function
        function RangeGridLuminance_Callback(app, event)
            
            % Hints: get(hObject,'String') returns contents of RangeGridLuminance as text
            %        str2double(get(hObject,'String')) returns contents of RangeGridLuminance as a double
            
            rL = str2double(app.RangeGridLuminance.Value);
            if isnan(rL)
                errordlg('You must enter a numeric value','Bad Input','modal')
            else
                if rL <= 1
                    app.RangeL = rL;
                end
            end
        end

        % Callback function
        function ScreenDistance_Callback(app, event)
            
            ScDist = str2double(app.ScreenDistance.Value);
            if isnan(ScDist) || ScDist < 0
                errordlg('You must enter a positive numeric value','Bad Input','modal');
            else
                app.ScreenDistanceV = abs(ScDist);
                SetScreenParameters(app);
            end
        end

        % Callback function
        function ScreenHeight_Callback(app, event)

            ScH = str2double(app.ScreenHeight.Value);
            if isnan(ScH)
                errordlg('You must enter a numeric value','Bad Input','modal');
            else
                app.ScreenHeightV = abs(ScH);
                SetScreenParameters(app);
            end
        end

        % Callback function
        function ScreenWidth_Callback(app, event)
            ScW = str2double(app.ScreenWidth.Value);
            if isnan(ScW)
                errordlg('You must enter a numeric value','Bad Input','modal');
            else
                app.ScreenWidthV = abs(ScW);
                SetScreenParameters(app);
            end
        end

        % Callback function
        function SingleGridRepetition_txt_Callback(app, event)
            
            nRepeat = str2double(app.SingleGridRepetition_txt.Value);
            if isnan(nRepeat)
                errordlg('You must enter a numeric value','Bad Input','modal')
            else
                app.SingleGridRepetition = nRepeat;
            end
        end

        % Value changed function: SliderOrientation
        function SliderOrientation_Callback(app, event)
            app.GridOrientationV = app.SliderOrientation.Value;
            app.GridOrientation.Value = int2str(app.GridOrientationV);
        end

        % Callback function
        function SpatialFrequency_txt_Callback(app, event)
            SpFreq = str2double(app.SpatialFrequency_txt.Value);
            if isnan(SpFreq)
                errordlg('You must enter a numeric value','Bad Input','modal')
            else
                app.SpatialFrequency = SpFreq;
            end
        end

        % Value changed function: StandbyL_txt
        function StandbyL_txt_Callback(app, event)
            % hObject    handle to StandbyL_txt (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            lumi = str2double(app.StandbyL_txt.Value);
            if isnan(lumi) || (lumi < 0) || (lumi > 1)
                errordlg('You must enter a numeric value in the range 0-1','Bad Input','modal');
            else
                app.StandbyL = lumi;
            end
        end

        % Callback function
        function WidthPixels_Callback(app, event)
            
            ScWpx = str2double(app.WidthPix.Value);
            if isnan(ScWpx)
                errordlg('You must enter a numeric value','Bad Input','modal');
            else
                app.WidthPixelsV = abs(ScWpx);
            end
        end

        % Button pushed function: autosetgrids_btn
        function autosetgrids_btn_Callback(app, event)
            % hObject    handle to autosetgrids_btn (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            app.GridOrientation.Value = num2str(0);
            GridOrientation_Callback(app.GridOrientation, eventdata, handles)
            handles = guidata(hObject);
            
            app.SpatialFrequency_txt.String = num2str(0.04);
            SpatialFrequency_txt_Callback(app.SpatialFrequency_txt, eventdata, handles)
            handles = guidata(hObject);
            
            app.DriftingSpeed_txt.String = num2str(2);
            DriftingSpeed_txt_Callback(app.DriftingSpeed_txt, eventdata, handles)
            handles = guidata(hObject);
            
            app.DrawMask.Value = 0;
            
            app.Baseline_txt.String = num2str(0.5);
            Baseline_txt_Callback(app.Baseline_txt, eventdata, handles)
            handles = guidata(hObject);
            
            app.GridDuration_txt.String = num2str(2);
            GridDuration_txt_Callback(app.GridDuration_txt, eventdata, handles)
            handles = guidata(hObject);
            
            app.PostGrid_txt.String = num2str(1.5);
            PostGrid_txt_Callback(app.PostGrid_txt, eventdata, handles)
            handles = guidata(hObject);
            
            app.MeanGridLuminance.String = num2str(0.182);
            MeanGridLuminance_Callback(app.MeanGridLuminance, eventdata, handles)
            handles = guidata(hObject);
            
            app.RangeGridLuminance.String = num2str(0.248);
            RangeGridLuminance_Callback(app.RangeGridLuminance, eventdata, handles)
            handles = guidata(hObject);
            
            app.SingleGridRepetition_txt.String = num2str(20);
            SingleGridRepetition_txt_Callback(app.SingleGridRepetition_txt, eventdata, handles)
            handles = guidata(hObject);
            
            app.SquareGrid.Value = 1;
            
            guidata(hObject,handles)
        end

        % Button pushed function: calblack_btn
        function calblack_btn_Callback(app, event)
            % hObject    handle to calblack_btn (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Starts the OpenGL session if in single screen mode
            global w screenRect;
            if app.OneScreenMode_ck.Value
                background = app.StandbyL;
                [w screenRect ifi white black grey] = OpenScreen (background);
            end
            Screen('FillRect', w, 0, screenRect);
            Screen('Flip', w);
        end

        % Button pushed function: calwhite_btn
        function calwhite_btn_Callback(app, event)
            % Fill the screen with white
            global w screenRect;
            if app.OneScreenMode_ck.Value
                background = app.StandbyL;
                [w screenRect ifi white black grey] = OpenScreen (background);
            end
            Screen('FillRect', w, 255, screenRect);
            Screen('Flip', w);
        end

        % Button pushed function: exit
        function exit_Callback(app, event)
            CloseScreen
        end

        % Value changed function: fastset_txt
        function fastset_txt_Callback(app, event)
            
            if isempty(app.fastset_txt.Value)
                return
            end
            setnumber = str2double(app.fastset_txt.Value);
            
            if ~isscalar(setnumber)
                error('only scalars are accepted')
            end
            
            if app.Checkerboard_ck.Value
                % CHECKERBOARD_CK SETTINGS
                if ~ismember(setnumber,1:6)
                    error('only numbers between 1 and 6 are accepted')
                end
            
                checkers = {'0.217','0.217';'0.210','0.224';'0.203','0.230';'0.188','0.243';'0.153','0.267';'0.058','0.306'};
            
                app.FSbaselineLumi_txt.Value = checkers{setnumber,1};
                FSbaselineLumi_txt_Callback(app.FSbaselineLumi_txt, eventdata, handles)
            
                app.FSstimLumi_txt.String = checkers{setnumber,2};
                FSstimLumi_txt_Callback(app.FSstimLumi_txt, eventdata, handles)
                handles=guidata(hObject);
            
            else
                % FLASH SETTINGS
                if ~ismember(setnumber,1:9)
                    error('only numbers between 1 and 9 are accepted')
                end
            
                flashes = {'0';'0.091';'0.106';'0.124';'0.145';'0.171';'0.202';'0.238';'0.282'};
            
                app.FSstim_txt.String = num2str(0.1);
                FSstim_txt_Callback(app.FSstim_txt, eventdata, handles)
                handles=guidata(hObject);
            
                app.FSbaselineLumi_txt.String = num2str(0);
                FSbaselineLumi_txt_Callback(app.FSbaselineLumi_txt, eventdata, handles)
                handles=guidata(hObject);
            
                app.FSstimLumi_txt.String = flashes{setnumber};
                FSstimLumi_txt_Callback(app.FSstimLumi_txt, eventdata, handles)
                handles=guidata(hObject);
            
            end
            
            % GENERAL SETTINGS
            app.FSn_txt.String = num2str(20);
            FSn_txt_Callback(app.FSn_txt, eventdata, handles)
            handles=guidata(hObject);
            
            app.FSperiod_txt.String = num2str(4);
            FSperiod_txt_Callback(app.FSperiod_txt, eventdata, handles)
            handles=guidata(hObject);
            
            app.FSbaseline_txt.String = num2str(1);
            FSbaseline_txt_Callback(app.FSbaseline_txt, eventdata, handles)
            handles=guidata(hObject);
            
            guidata(hObject,handles)
        end

        % Button pushed function: StartIP
        function startServerMode(app, event)
            % December 5, 2018
            % Escher enters in server mode by listening to the specified port for connections 
            % originating from the specified IPs on the specified port.
            % The trasferred data are formed by a numeric array containing all fields necessary to
            % described the requested stimuli. 
            % Field description:
            % dataIn (1) numeric code of the requested service as described below
            % dataIn (2)
            
            % The function keeps the app in a listening loop. When the data are received the stimulus
            % sequence is started. After completition the funtion returns inside the listening loop.
            % The loop is excited only by clicking off the Server mode check box.
            
            % First: toggle the serverMode CB (not sure if it is necessary...)
            
            if app.ServerModeCB.Value
                app.ServerModeCB.Value = 0;
                fclose(t)
            else
                % First, open the IP socket as server, timeout 1 hr.
                t = tcpip(app.AcceptedIP.Value, app.Port.Value, 'NetworkRole', 'server','Timeout', 3600);
                fopen(t);
                while app.ServerModeCB.Value
                    % Welcome in served mode!
                    dataIn = fread(t, t.BytesAvailable, 'float32');
                    % Generate the requested stimuli
                    % dataIn (1) = 1: Set baseline luminance; 2: flash; 3: checkerboard; 4: sine grid; 
                    % 5 square grid.
                    % dataIn (2): baseline L; (3) 
                    % eccetera
                    
                    % prepare the stim structure
                    
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Escher2';
            app.uipanel4 = uipanel(app.figure1);
            app.uipanel4.Title = 'Monitor Size and Geometry';
            app.ScreenSizeAngle = uilabel(app.uipanel4);
            app.ScreenSizeAngle.Text = 'Angular size of screen:';
            app.ScreendistancewidthheightcmLabel = uilabel(app.uipanel4);
            app.ScreendistancewidthheightcmLabel.Tooltip = {'Enter the three parameters definig the screen angular size. '};
            app.ScreendistancewidthheightcmLabel.Text = 'Screen distance-width-height (cm):';
            app.ScreenDistance = uieditfield(app.uipanel4, 'numeric');
            app.ScreenDistance.Limits = [0 120];
            app.ScreenDistance.RoundFractionalValues = 'on';
            app.ScreenDistance.ValueDisplayFormat = '%4.3g';
            app.ScreenDistance.Value = 30;
            app.ScreenWidth = uieditfield(app.uipanel4, 'numeric');
            app.ScreenWidth.Limits = [0 120];
            app.ScreenWidth.ValueDisplayFormat = '%4.3g';
            app.ScreenWidth.Value = 34;
            app.ScreenHeight = uieditfield(app.uipanel4, 'numeric');
            app.ScreenHeight.Limits = [0 Inf];
            app.ScreenHeight.ValueDisplayFormat = '%4.3g';
            app.ScreenHeight.Value = 27;
            app.WidthpixelsEditFieldLabel = uilabel(app.uipanel4);
            app.WidthpixelsEditFieldLabel.Text = 'Width (pixels):';
            app.WidthPix = uieditfield(app.uipanel4, 'numeric');
            app.WidthPix.Limits = [0 Inf];
            app.WidthPix.RoundFractionalValues = 'on';
            app.WidthPix.Value = 1240;
            app.HeightpixelsEditFieldLabel = uilabel(app.uipanel4);
            app.HeightpixelsEditFieldLabel.Text = 'Height (pixels):';
            app.HeightPix = uieditfield(app.uipanel4, 'numeric');
            app.HeightPix.Limits = [0 Inf];
            app.HeightPix.RoundFractionalValues = 'on';
            app.HeightPix.Value = 800;
            app.uipanel2 = uipanel(app.figure1);
            app.uipanel2.Title = 'Periodic Grids and Reticles';
            app.text10 = uilabel(app.uipanel2);
            app.text10.Text = 'Grid orientation (deg):';
            app.text12 = uilabel(app.uipanel2);
            app.text12.Text = 'Drifting speed (cycles/sec):';
            app.text13 = uilabel(app.uipanel2);
            app.text13.Text = 'Size gaussian mask (pix):';
            app.GaussSize_txt = uieditfield(app.uipanel2, 'text');
            app.GaussSize_txt.ValueChangedFcn = createCallbackFcn(app, @GaussSize_txt_Callback, true);
            app.GaussSize_txt.Value = '300';
            app.DriftingSpeed_txt = uieditfield(app.uipanel2, 'text');
            app.DriftingSpeed_txt.ValueChangedFcn = createCallbackFcn(app, @DriftingSpeed_txt_Callback, true);
            app.DriftingSpeed_txt.Value = '1.0';
            app.GridOrientation = uieditfield(app.uipanel2, 'text');
            app.GridOrientation.ValueChangedFcn = createCallbackFcn(app, @GridOrientation_Callback, true);
            app.GridOrientation.Value = '45';
            app.SliderOrientation = uislider(app.uipanel2);
            app.SliderOrientation.Limits = [0 360];
            app.SliderOrientation.MajorTicks = [];
            app.SliderOrientation.ValueChangedFcn = createCallbackFcn(app, @SliderOrientation_Callback, true);
            app.SliderOrientation.MinorTicks = [];
            app.SliderOrientation.Value = 50;
            app.uipanel9 = uibuttongroup(app.uipanel2);
            app.SineGrid = uiradiobutton(app.uipanel9);
            app.SineGrid.Text = 'Sinusoidal';
            app.SineGrid.Value = true;
            app.SquareGrid = uiradiobutton(app.uipanel9);
            app.SquareGrid.Text = 'Square';
            app.Checkerboard_ck = uicheckbox(app.uipanel9);
            app.Checkerboard_ck.ValueChangedFcn = createCallbackFcn(app, @Checkerboard_ck_Callback, true);
            app.Checkerboard_ck.Tooltip = 'The screen will display a checkerboard. Spatial freq as defined in the grid panel';
            app.Checkerboard_ck.Text = 'Checkerboard';
            app.GoGrid = uibutton(app.uipanel2, 'push');
            app.GoGrid.ButtonPushedFcn = createCallbackFcn(app, @GoGrid_Callback, true);
            app.GoGrid.Text = 'Go';
            app.DrawMask = uicheckbox(app.uipanel2);
            app.DrawMask.Text = '';
            app.DrawMask.Value = true;
            app.autosetgrids_btn = uibutton(app.uipanel2, 'push');
            app.autosetgrids_btn.ButtonPushedFcn = createCallbackFcn(app, @autosetgrids_btn_Callback, true);
            app.autosetgrids_btn.Text = 'Auto-Set';
            app.FrequencycyclesDegLabel = uilabel(app.uipanel2);
            app.FrequencycyclesDegLabel.Tooltip = {'Spatial frequency expressed in cycles/Deg'};
            app.FrequencycyclesDegLabel.Text = 'Frequency (cycles/Deg):';
            app.spatialFrequency = uieditfield(app.uipanel2, 'numeric');
            app.spatialFrequency.Limits = [0 Inf];
            app.spatialFrequency.ValueDisplayFormat = '%5.3g';
            app.spatialFrequency.Tooltip = {'Spatial frequency expressed in cycles/Deg'};
            app.spatialFrequency.Value = 0.5;
            app.MeanL01Label = uilabel(app.uipanel2);
            app.MeanL01Label.Text = 'Mean L (0-1):';
            app.patternMeanL = uieditfield(app.uipanel2, 'numeric');
            app.patternMeanL.Limits = [0 1];
            app.patternMeanL.Value = 0.5;
            app.rangeLabel = uilabel(app.uipanel2);
            app.rangeLabel.Text = '+ range:';
            app.plusPatternL = uieditfield(app.uipanel2, 'numeric');
            app.plusPatternL.Limits = [0 1];
            app.plusPatternL.Tooltip = {'The max and min luminance of the pattern is obtained by adding and subtracting these two parameters. They are identical if the monitor is linearized with a LUT.'};
            app.plusPatternL.Value = 0.5;
            app.rangeLabel_2 = uilabel(app.uipanel2);
            app.rangeLabel_2.Tooltip = {'The max and min luminance of the pattern is obtained by adding and subtracting these two parameters. They are identical if the monitor is linearized with a LUT.'};
            app.rangeLabel_2.Text = '- range:';
            app.minusPatternL = uieditfield(app.uipanel2, 'numeric');
            app.minusPatternL.Limits = [-1 0];
            app.minusPatternL.Tooltip = {'The max and min luminance of the pattern is obtained by adding and subtracting these two parameters. They are identical if the monitor is linearized with a LUT.'};
            app.minusPatternL.Value = -0.5;
            app.ButtonGroup = uibuttongroup(app.uipanel2);
            app.SinusoidalButton = uiradiobutton(app.ButtonGroup);
            app.SinusoidalButton.Text = 'Sinusoidal';
            app.SinusoidalButton.Value = true;
            app.SquareButton = uiradiobutton(app.ButtonGroup);
            app.SquareButton.Text = 'Square';
            app.CheckerboardButton = uiradiobutton(app.ButtonGroup);
            app.CheckerboardButton.Text = 'Checkerboard';
            app.FullScreen = uipanel(app.figure1);
            app.FullScreen.Title = 'Full and Partial Field Flash';
            app.FSgo = uibutton(app.FullScreen, 'push');
            app.FSgo.ButtonPushedFcn = createCallbackFcn(app, @FSgo_Callback, true);
            app.FSgo.Text = 'GO!';
            app.text61 = uilabel(app.FullScreen);
            app.text61.Text = 'Fast Set:';
            app.fastset_txt = uieditfield(app.FullScreen, 'text');
            app.fastset_txt.ValueChangedFcn = createCallbackFcn(app, @fastset_txt_Callback, true);
            app.Baselineluminance01EditFieldLabel = uilabel(app.FullScreen);
            app.Baselineluminance01EditFieldLabel.Tooltip = {'Luminance of the full screen away from the flash.'};
            app.Baselineluminance01EditFieldLabel.Text = 'Baseline luminance (0-1):';
            app.FSbaselineL = uieditfield(app.FullScreen, 'numeric');
            app.FSbaselineL.Limits = [0 1];
            app.FSbaselineL.ValueDisplayFormat = '%5.3g';
            app.FSbaselineL.Tooltip = {'Luminance of the background'};
            app.FSbaselineL.Value = 0.2;
            app.FlashLabel = uilabel(app.FullScreen);
            app.FlashLabel.Text = {'Flash:'; ''};
            app.FSflashL = uieditfield(app.FullScreen, 'numeric');
            app.FSflashL.Limits = [0 1];
            app.FSflashL.Tooltip = {'Luminance of the flash stimulus'};
            app.FSflashL.Value = 0.8;
            app.uipanel6 = uipanel(app.figure1);
            app.uipanel6.Title = 'Setting Screen and Synchronisation';
            app.OpenScreen = uibutton(app.uipanel6, 'push');
            app.OpenScreen.ButtonPushedFcn = createCallbackFcn(app, @OpenScreen_Callback, true);
            app.OpenScreen.Tooltip = {'Open screen when in dual monitor mode (default)'};
            app.OpenScreen.Text = 'Open Screen';
            app.text38 = uilabel(app.uipanel6);
            app.text38.Text = 'Set standby luminance (0-1):';
            app.StandbyL_txt = uieditfield(app.uipanel6, 'text');
            app.StandbyL_txt.ValueChangedFcn = createCallbackFcn(app, @StandbyL_txt_Callback, true);
            app.StandbyL_txt.Value = '0.5';
            app.RefreshStBy = uibutton(app.uipanel6, 'push');
            app.RefreshStBy.Tooltip = {'Fill screen with stand by luminance'};
            app.RefreshStBy.Text = 'Standby';
            app.OneScreenMode_ck = uicheckbox(app.uipanel6);
            app.OneScreenMode_ck.ValueChangedFcn = createCallbackFcn(app, @OneScreenMode_ck_Callback, true);
            app.OneScreenMode_ck.Text = 'One Screen Mode';
            app.CalibrateLUT = uicheckbox(app.uipanel6);
            app.CalibrateLUT.ValueChangedFcn = createCallbackFcn(app, @CalibrateLUT_Callback, true);
            app.CalibrateLUT.Tooltip = {'Load a calibration LUT. Luminance data will be transformed to linearize the monitor response'};
            app.CalibrateLUT.Text = 'LUT';
            app.oculusCk = uicheckbox(app.uipanel6);
            app.oculusCk.Text = 'VR goggles';
            app.calblack_btn = uibutton(app.uipanel6, 'push');
            app.calblack_btn.ButtonPushedFcn = createCallbackFcn(app, @calblack_btn_Callback, true);
            app.calblack_btn.Text = 'C-Black';
            app.calwhite_btn = uibutton(app.uipanel6, 'push');
            app.calwhite_btn.ButtonPushedFcn = createCallbackFcn(app, @calwhite_btn_Callback, true);
            app.calwhite_btn.Text = 'C-White';
            app.ard_ck = uicheckbox(app.uipanel6);
            app.ard_ck.Tooltip = {'Display the Hermes synchronisation cue'};
            app.ard_ck.Text = 'Hermes Synch';
            app.ard_ck.Value = true;
            app.text58 = uilabel(app.uipanel6);
            app.text58.Text = 'Duration of cue (ms):';
            app.optDtrTimeTxt = uieditfield(app.uipanel6, 'text');
            app.optDtrTimeTxt.Tooltip = 'Duration of optical DTR (in ms)';
            app.optDtrTimeTxt.Value = '100';
            app.text60 = uilabel(app.uipanel6);
            app.text60.Text = 'Cue width (pixels):';
            app.wwTxt = uieditfield(app.uipanel6, 'text');
            app.wwTxt.Value = '100';
            app.hhTxt = uieditfield(app.uipanel6, 'text');
            app.hhTxt.Value = '100';
            app.text60_2 = uilabel(app.uipanel6);
            app.text60_2.Text = 'Height (pixels):';
            app.AcceptIPEditFieldLabel = uilabel(app.uipanel6);
            app.AcceptIPEditFieldLabel.Text = 'Accept IP:';
            app.AcceptedIP = uieditfield(app.uipanel6, 'text');
            app.AcceptedIP.Tooltip = {'Set here the IP number of the client using the Escher server. Set it to 0.0.0.0 to accept connections from any IPs.'};
            app.AcceptedIP.Value = '0.0.0.0';
            app.PortEditFieldLabel = uilabel(app.uipanel6);
            app.PortEditFieldLabel.Text = 'Port:';
            app.Port = uieditfield(app.uipanel6, 'numeric');
            app.Port.Limits = [1000 Inf];
            app.Port.ValueDisplayFormat = '%8.5g';
            app.Port.Tooltip = {'Port setting: the same port must be indicated here in the server side and on the client.'};
            app.Port.Value = 30000;
            app.StartIP = uibutton(app.uipanel6, 'push');
            app.StartIP.ButtonPushedFcn = createCallbackFcn(app, @startServerMode, true);
            app.StartIP.Tooltip = {'Beging server mode by listening to the IP connection and performing the requested tasks'};
            app.StartIP.Text = 'Server';
            app.ServerModeCB = uicheckbox(app.uipanel6);
            app.ServerModeCB.Tooltip = {'Shows if the app is listenting to the IP socket for incoming service requests. Unclick it to stop the listening mode'};
            app.ServerModeCB.Text = 'Server Mode';
            app.uipanel7 = uipanel(app.figure1);
            app.uipanel7.Title = 'Pseudo-random Grid Sequence';
            app.text222 = uilabel(app.uipanel7);
            app.text222.Text = 'How many orientations?';
            app.text43 = uilabel(app.uipanel7);
            app.text43.Text = 'Press the ''@'' key to interrupt';
            app.CycleN = uieditfield(app.uipanel7, 'text');
            app.CycleN.Tooltip = 'http://www.nature.com/srep/2011/110725/srep00045/full/srep00045.html';
            app.CycleN.Value = '4';
            app.OrienN = uieditfield(app.uipanel7, 'text');
            app.OrienN.ValueChangedFcn = createCallbackFcn(app, @OrienN_Callback, true);
            app.OrienN.Tooltip = 'http://www.nature.com/srep/2011/110725/srep00045/full/srep00045.html';
            app.OrienN.Value = '8';
            app.Status = uilabel(app.uipanel7);
            app.Status.Text = 'Sweep x of y';
            app.GoRandomGrid = uibutton(app.uipanel7, 'push');
            app.GoRandomGrid.ButtonPushedFcn = createCallbackFcn(app, @GoRandomGrid_Callback, true);
            app.GoRandomGrid.Tooltip = 'Questo compare quando passo con il mouse';
            app.GoRandomGrid.Text = 'GO!';
            app.exit = uibutton(app.uipanel7, 'push');
            app.exit.ButtonPushedFcn = createCallbackFcn(app, @exit_Callback, true);
            app.exit.Text = 'Exit';
            app.text42 = uilabel(app.uipanel7);
            app.text42.Text = 'Cycle repetitions:';
            app.uipanel14 = uipanel(app.figure1);
            app.uipanel14.Title = 'Stimulus Timing';
            app.InitialdelaysLabel = uilabel(app.uipanel14);
            app.InitialdelaysLabel.Text = 'Initial delay (s):';
            app.initialDelay = uieditfield(app.uipanel14, 'numeric');
            app.initialDelay.Limits = [0 Inf];
            app.initialDelay.Tooltip = {'Initial delay from the start of the sequence to the apparition of the first stimulus. The screen is set to the baseline or mean luminance'};
            app.initialDelay.Value = 3;
            app.StimulussEditFieldLabel = uilabel(app.uipanel14);
            app.StimulussEditFieldLabel.Tooltip = {'The meaning of this depends on the selected stimulus: FLASH duration'; ' CHECKER: one on-off period'; ' GRID: duration.'};
            app.StimulussEditFieldLabel.Text = 'Stimulus (s)';
            app.stimT = uieditfield(app.uipanel14, 'numeric');
            app.stimT.Limits = [0 Inf];
            app.stimT.ValueDisplayFormat = '%6.4g';
            app.stimT.Tooltip = {'The meaning of this depends on the selected stimulus: FLASH duration'; ' CHECKER: one on-off period.'};
            app.stimT.Value = 0.05;
            app.PeriodsLabel = uilabel(app.uipanel14);
            app.PeriodsLabel.Tooltip = {'FLASH: period between successive flashes. '; 'GRID: the period is given by the sum of stimulus and post stim. CHECKER: duration of a single period(one reversal.'};
            app.PeriodsLabel.Text = 'Period (s):';
            app.period = uieditfield(app.uipanel14, 'numeric');
            app.period.Limits = [0 Inf];
            app.period.ValueDisplayFormat = '%6.3g';
            app.period.Tooltip = {'FLASH: period between successive flashes. '; 'GRID: the period is given by the sum of stimulus and post stim. CHECKER: duration of a single period (one reversal).'};
            app.period.Value = 2;
            app.RepetitionsLabel = uilabel(app.uipanel14);
            app.RepetitionsLabel.Tooltip = {'Number of stimuli or checkerboard reversal in the sequence'};
            app.RepetitionsLabel.Text = 'Repetitions:';
            app.stimRepetitions = uieditfield(app.uipanel14, 'numeric');
            app.stimRepetitions.Limits = [1 Inf];
            app.stimRepetitions.ValueDisplayFormat = '%4.0g';
            app.stimRepetitions.Tooltip = {'Number of stimuli or checkerboard reversal in the sequence'};
            app.stimRepetitions.Value = 2;
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EscherApp_standalone

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @Escher2_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end